classdef UNLSI_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Menu                   matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
        Toolbar                matlab.ui.container.Toolbar
        PushTool               matlab.ui.container.toolbar.PushTool
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        GridLayout16           matlab.ui.container.GridLayout
        halfMeshDrop           matlab.ui.control.DropDown
        Label_5                matlab.ui.control.Label
        mergeTolFiled          matlab.ui.control.NumericEditField
        Label_6                matlab.ui.control.Label
        VSPGEOMPanel           matlab.ui.container.Panel
        GridLayout25           matlab.ui.container.GridLayout
        readMeshButton         matlab.ui.control.Button
        readMeshButton_2       matlab.ui.control.Button
        Spinner                matlab.ui.control.Spinner
        Label_14               matlab.ui.control.Label
        makeEquationFIeld      matlab.ui.control.Button
        VSPGEOMEditField       matlab.ui.control.EditField
        Panel_8                matlab.ui.container.Panel
        GridLayout9            matlab.ui.container.GridLayout
        execAnalysesButton_2   matlab.ui.control.Button
        ADFlagDrop             matlab.ui.control.DropDown
        alphaBetaDrop          matlab.ui.control.DropDown
        analysisProgressLabel  matlab.ui.control.Label
        flowSelectDrop         matlab.ui.control.DropDown
        execAnalysesButton     matlab.ui.control.Button
        Panel_3                matlab.ui.container.Panel
        GridLayout6            matlab.ui.container.GridLayout
        ReField                matlab.ui.control.NumericEditField
        MachField              matlab.ui.control.NumericEditField
        dbetaFiled             matlab.ui.control.NumericEditField
        betaMaxField           matlab.ui.control.NumericEditField
        betaMinField           matlab.ui.control.NumericEditField
        dalphaField            matlab.ui.control.NumericEditField
        alphaMaxField          matlab.ui.control.NumericEditField
        alphaMinField          matlab.ui.control.NumericEditField
        Re1e4Label             matlab.ui.control.Label
        MachLabel              matlab.ui.control.Label
        degLabel               matlab.ui.control.Label
        degLabel_2             matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
        ZREFEditField          matlab.ui.control.NumericEditField
        ZREFEditFieldLabel     matlab.ui.control.Label
        YREFEditField          matlab.ui.control.NumericEditField
        YREFEditFieldLabel     matlab.ui.control.Label
        XREFEditField          matlab.ui.control.NumericEditField
        XREFEditFieldLabel     matlab.ui.control.Label
        CREFEditField          matlab.ui.control.NumericEditField
        CREFEditFieldLabel     matlab.ui.control.Label
        BREFEditField          matlab.ui.control.NumericEditField
        BREFEditFieldLabel     matlab.ui.control.Label
        SREFEditField          matlab.ui.control.NumericEditField
        SREFEditFieldLabel     matlab.ui.control.Label
        CenterPanel            matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        Tab_5                  matlab.ui.container.Tab
        GridLayout20           matlab.ui.container.GridLayout
        Panel_13               matlab.ui.container.Panel
        GridLayout24           matlab.ui.container.GridLayout
        actGainTable           matlab.ui.control.Table
        actSelDrop             matlab.ui.control.DropDown
        actNoField             matlab.ui.control.NumericEditField
        EditField_4Label       matlab.ui.control.Label
        actIDField             matlab.ui.control.ListBox
        Panel_12               matlab.ui.container.Panel
        GridLayout23           matlab.ui.container.GridLayout
        propDiaField           matlab.ui.control.NumericEditField
        Label_11               matlab.ui.control.Label
        propSymField           matlab.ui.control.DropDown
        propNoField            matlab.ui.control.NumericEditField
        EditField_3Label       matlab.ui.control.Label
        rpmLabel               matlab.ui.control.Label
        CPLabel                matlab.ui.control.Label
        CTLabel                matlab.ui.control.Label
        propIDField            matlab.ui.control.ListBox
        RPMEditField           matlab.ui.control.NumericEditField
        CPEditField            matlab.ui.control.NumericEditField
        CTEditField            matlab.ui.control.NumericEditField
        propSelDrop            matlab.ui.control.DropDown
        Panel_10               matlab.ui.container.Panel
        GridLayout21           matlab.ui.container.GridLayout
        UITable2               matlab.ui.control.Table
        Tab_4                  matlab.ui.container.Tab
        GridLayout17           matlab.ui.container.GridLayout
        Button_8               matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        Panel_9                matlab.ui.container.Panel
        GridLayout18           matlab.ui.container.GridLayout
        msLabel                matlab.ui.control.Label
        readMassPropButton_2   matlab.ui.control.Button
        dynVinfFIeld           matlab.ui.control.NumericEditField
        Label_12               matlab.ui.control.Label
        dimAnalysisButton      matlab.ui.control.Button
        readMassPropButton     matlab.ui.control.Button
        massPropField          matlab.ui.control.EditField
        Button_5               matlab.ui.control.Button
        dynCoefButton          matlab.ui.control.Button
        UIAxes6                matlab.ui.control.UIAxes
        UIAxes6_2              matlab.ui.control.UIAxes
        Tab_3                  matlab.ui.container.Tab
        GridLayout15           matlab.ui.container.GridLayout
        Panel_6                matlab.ui.container.Panel
        GridLayout10           matlab.ui.container.GridLayout
        UITable                matlab.ui.control.Table
        TabGroup2              matlab.ui.container.TabGroup
        Tab_6                  matlab.ui.container.Tab
        GridLayout27           matlab.ui.container.GridLayout
        UIAxes5                matlab.ui.control.UIAxes
        Tab_7                  matlab.ui.container.Tab
        GridLayout28           matlab.ui.container.GridLayout
        UIAxes7                matlab.ui.control.UIAxes
        IDPanel                matlab.ui.container.Panel
        GridLayout12           matlab.ui.container.GridLayout
        fem2aeroList           matlab.ui.control.ListBox
        FEMmeshPanel           matlab.ui.container.Panel
        GridLayout8            matlab.ui.container.GridLayout
        femVinfFIeld           matlab.ui.control.NumericEditField
        Label_17               matlab.ui.control.Label
        selfLoadDrop           matlab.ui.control.DropDown
        solveFemButton         matlab.ui.control.Button
        readFemMeshButton_2    matlab.ui.control.Button
        modalNoDrop            matlab.ui.control.DropDown
        Label_13               matlab.ui.control.Label
        modalDimField          matlab.ui.control.NumericEditField
        makeFemEquationButton  matlab.ui.control.Button
        FEAmeshEditField       matlab.ui.control.EditField
        readFemMeshButton      matlab.ui.control.Button
        Tab_2                  matlab.ui.container.Tab
        GridLayout14           matlab.ui.container.GridLayout
        TabGroup3              matlab.ui.container.TabGroup
        zTab                   matlab.ui.container.Tab
        GridLayout30           matlab.ui.container.GridLayout
        UITable3               matlab.ui.control.Table
        zdotTab                matlab.ui.container.Tab
        UITable3_2             matlab.ui.control.Table
        Panel_16               matlab.ui.container.Panel
        GridLayout26_2         matlab.ui.container.GridLayout
        Button_6               matlab.ui.control.Button
        DropDown               matlab.ui.control.DropDown
        Label_18               matlab.ui.control.Label
        TextArea_2             matlab.ui.control.TextArea
        Panel_14               matlab.ui.container.Panel
        GridLayout26           matlab.ui.container.GridLayout
        femVinfFIeld_2         matlab.ui.control.NumericEditField
        Label_19               matlab.ui.control.Label
        Button_7               matlab.ui.control.Button
        UIAxes5_2              matlab.ui.control.UIAxes
        Tab_8                  matlab.ui.container.Tab
        GridLayout29           matlab.ui.container.GridLayout
        Panel_15               matlab.ui.container.Panel
        GridLayout11_4         matlab.ui.container.GridLayout
        abortDrop_2            matlab.ui.control.DropDown
        solveElasticButton_2   matlab.ui.control.Button
        abortDrop              matlab.ui.control.DropDown
        solveElasticButton     matlab.ui.control.Button
        elasticdtField         matlab.ui.control.NumericEditField
        Label_16               matlab.ui.control.Label
        elasticTimeField       matlab.ui.control.NumericEditField
        Label_15               matlab.ui.control.Label
        UIAxes4_2              matlab.ui.control.UIAxes
        UIAxes4                matlab.ui.control.UIAxes
        RightPanel             matlab.ui.container.Panel
        GridLayout13           matlab.ui.container.GridLayout
        Panel_2                matlab.ui.container.Panel
        GridLayout5            matlab.ui.container.GridLayout
        YLabel                 matlab.ui.control.Label
        XLabel                 matlab.ui.control.Label
        ylabelList             matlab.ui.control.ListBox
        xlabelList             matlab.ui.control.ListBox
        UIAxes_2               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        lsi % 解析用
        location %作業ディレクトリ
        var %一時保存用
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function: PushTool
        function Initialize(app, event)
            app.lsi = [];
            app.execAnalysesButton.Enable = "off";
            app.makeEquationFIeld.Enable = "off";
            app.SREFEditField.Enable = "on";
            app.BREFEditField.Enable = "on";
            app.CREFEditField.Enable = "on";
            app.XREFEditField.Enable = "on";
            app.YREFEditField.Enable = "on";
            app.ZREFEditField.Enable = "on";
            app.mergeTolFiled.Enable = "on";
            app.SREFEditField.Value = 1;
            app.BREFEditField.Value = 1;
            app.CREFEditField.Value = 1;
            app.XREFEditField.Value = 0;
            app.YREFEditField.Value = 0;
            app.ZREFEditField.Value = 0;
            app.halfMeshDrop.Value = -1;
            app.mergeTolFiled.Value = 0;
            app.VSPGEOMEditField.Value = [];
        end

        % Button pushed function: readMeshButton
        function readMeshButtonPushed(app, event)
            if isempty(app.VSPGEOMEditField.Value)
                if isempty(app.location)
                    [file,locbuff] = uigetfile("*.vspgeom","VSPGEOMファイルを選択");
                else
                    [file,locbuff] = uigetfile("*.vspgeom","VSPGEOMファイルを選択",app.location);
                end
                if not(file == 0)
                    app.location = locbuff;
                    app.VSPGEOMEditField.Value = fullfile(app.location,file);
                end
            end
            if not(isempty(app.VSPGEOMEditField.Value))
                app.analysisProgressLabel.Text = "空力メッシュ読込中";
                drawnow();
                [con, p, uv1, uv2, uv3, wedata, id] = guiFiles.readvspgeom(app.VSPGEOMEditField.Value, 0); %形状の読み込み
                mergeTol = app.mergeTolFiled.Value;
                if app.halfMeshDrop.Value == -1
                    app.lsi = guiFiles.UNLSI(p',con',id',wedata,[],mergeTol); %コンストラクタの実行
                else
                    app.lsi = guiFiles.UNLSI(p',con',id',wedata,app.halfMeshDrop.Value,mergeTol); %コンストラクタの実行
                end
                
                if app.lsi.halfmesh == 1
                    set(app.halfMeshDrop,"Value",1);
                else
                    set(app.halfMeshDrop,"Value",0);
                end
                app.lsi = app.lsi.setREFS(app.SREFEditField.Value,app.BREFEditField.Value,app.CREFEditField.Value,[app.XREFEditField.Value,app.YREFEditField.Value,app.ZREFEditField.Value]); %基準面積 基準長の設定
                app.lsi = app.lsi.checkWakeIntersect();
                app.lsi = app.lsi.setUNLSISettings("nCalcDivide",app.Spinner.Value);
                app.lsi.plotGeometry(app.UIAxes);%圧力係数のプロット
                app.makeEquationFIeld.Enable = "on";
                app.execAnalysesButton.Enable = "off";
                app.propNoField.Value = 0;
                app.propNoFieldValueChanged();
                app.actNoField.Value = 0;
                app.actNoFieldValueChanged();
                app.fem2aeroList.Items = {};
                app.fem2aeroList.ItemsData = [];
                uniqueID = unique(app.lsi.surfID);
                for i = 1:numel(uniqueID)
                    app.fem2aeroList.Items = [app.fem2aeroList.Items,num2str(uniqueID(i))];
                    app.fem2aeroList.ItemsData = [app.fem2aeroList.ItemsData,uniqueID(i)];
                end
                app.fem2aeroList.Value = app.fem2aeroList.ItemsData;
                %舵面設定
                app.UITable2.Data = app.lsi.deflAngle(:,[1,5,2:4]);
                app.analysisProgressLabel.Text = "空力メッシュ読込完了";
            end
        end

        % Button pushed function: readMeshButton_2
        function readMeshButton_2Pushed(app, event)
            app.VSPGEOMEditField.Value = '';
        end

        % Display data changed function: UITable2
        function UITable2DisplayDataChanged(app, event)
            for i = 1:size(app.UITable2.Data,1)
                app.lsi = app.lsi.setDeflAngle(app.UITable2.Data(i,1),app.UITable2.Data(i,3:5),app.UITable2.Data(i,2));
            end
        end

        % Button pushed function: makeEquationFIeld
        function makeEquationFIeldPushed(app, event)
            app.lsi = app.lsi.setREFS(app.SREFEditField.Value,app.BREFEditField.Value,app.CREFEditField.Value,[app.XREFEditField.Value,app.YREFEditField.Value,app.ZREFEditField.Value]); %基準面積 基準長の設定
            app.lsi = app.lsi.setUNLSISettings("nCalcDivide",app.Spinner.Value);
            app.lsi = app.lsi.checkWakeIntersect();
            app.analysisProgressLabel.Text = "パネル法クラスター作成中";
            drawnow();
            app.lsi = app.lsi.makeCluster(); %速度分布を求めるためのパネルクラスターを作成
            app.analysisProgressLabel.Text = "パネル法行列作成中";
            drawnow();
            app.lsi = app.lsi.makeEquation(); %パネル法行列の作成
            app.analysisProgressLabel.Text = "パネル法行列作成完了";
            app.execAnalysesButton.Enable = "on";
        end

        % Button pushed function: execAnalysesButton
        function execAnalysesButtonPushed(app, event)
            app.lsi = app.lsi.setREFS(app.SREFEditField.Value,app.BREFEditField.Value,app.CREFEditField.Value,[app.XREFEditField.Value,app.YREFEditField.Value,app.ZREFEditField.Value]); %基準面積 基準長の設定
            app.lsi = app.lsi.checkWakeIntersect();
            app.lsi = app.lsi.setUNLSISettings("propCalcFlag",app.ADFlagDrop.Value);
            app.analysisProgressLabel.Text = "空力解析実行中";
            drawnow();
            alpharange = app.alphaMinField.Value:app.dalphaField.Value:app.alphaMaxField.Value;
            betarange = app.betaMinField.Value:app.dbetaFiled.Value:app.betaMaxField.Value;
            [AA,BB] = ndgrid(alpharange,betarange);
            app.lsi = app.lsi.solveFlow(AA(:),BB(:),app.MachField.Value,app.ReField.Value*1e4);%パネル法を解く
            app.analysisProgressLabel.Text = "空力解析実行完了";
            app.flowSelectDrop.Enable = "on";
            app.alphaBetaDrop.Enable = "on";
            app.xlabelList.Enable = "on";
            app.ylabelList.Enable = "on";
            app.execAnalysesButton_2.Enable = "on";
            app.flowSelectDrop.Items = {};
            for i = 1:numel(app.lsi.AERODATA)
                app.flowSelectDrop.Items = [app.flowSelectDrop.Items,strcat("Mach ",num2str(app.lsi.flow{i}.Mach)," Re(1e4) ",num2str(round(app.lsi.flow{i}.Re/10000)))];
            end
            app.flowSelectDrop.ItemsData = 1:numel(app.lsi.AERODATA);
            app.flowSelectDrop.Value = 1;
            app.alphaBetaDrop.Items = {};
            for i = 1:size(app.lsi.AERODATA{app.flowSelectDrop.Value},1)
                app.alphaBetaDrop.Items = [app.alphaBetaDrop.Items,strcat("Alfa ",num2str(app.lsi.AERODATA{app.flowSelectDrop.Value}(i,3))," Beta ",num2str(app.lsi.AERODATA{app.flowSelectDrop.Value}(i,1)))];
            end
            app.alphaBetaDrop.ItemsData = 1:size(app.lsi.AERODATA{app.flowSelectDrop.Value},1);
            app.alphaBetaDrop.Value = 1;
            
            app.lsi.plotGeometry(app.UIAxes,app.lsi.Cp{app.flowSelectDrop.Value}(:,app.alphaBetaDrop.Value),[-2,1]);%圧力係数のプロット
            plot(app.lsi.AERODATA{app.flowSelectDrop.Value}(:,app.xlabelList.Value),app.lsi.AERODATA{app.flowSelectDrop.Value}(:,app.ylabelList.Value),"-o","Parent",app.UIAxes_2);
            grid(app.UIAxes_2,"on");
            xlabel(app.UIAxes_2,app.xlabelList.Items{(app.xlabelList.Value == app.xlabelList.ItemsData)});
            ylabelName = [];
            for i = 1:numel(app.ylabelList.Value)
                ylabelName = strcat(ylabelName,app.ylabelList.Items{(app.ylabelList.Value(i) == app.ylabelList.ItemsData)}," ");
            end
            ylabel(app.UIAxes_2,ylabelName);
            title(app.UIAxes_2,app.flowSelectDrop.Items{(app.flowSelectDrop.Value == app.flowSelectDrop.ItemsData)});
            app.dynCoefButton.Enable = "on";
            app.readFemMeshButton.Enable = "on";
            app.readFemMeshButton_2.Enable = "on";
        end

        % Value changed function: flowSelectDrop
        function flowSelectDropValueChanged(app, event)
            app.solveElasticButton.Enable = "off";
            app.lsi.plotGeometry(app.UIAxes,app.lsi.Cp{app.flowSelectDrop.Value}(:,app.alphaBetaDrop.Value),[-2,1]);%圧力係数のプロット
            plot(app.lsi.AERODATA{app.flowSelectDrop.Value}(:,app.xlabelList.Value),app.lsi.AERODATA{app.flowSelectDrop.Value}(:,app.ylabelList.Value),"-o","Parent",app.UIAxes_2);
        end

        % Value changed function: alphaBetaDrop
        function alphaBetaDropValueChanged(app, event)
            app.lsi.plotGeometry(app.UIAxes,app.lsi.Cp{app.flowSelectDrop.Value}(:,app.alphaBetaDrop.Value),[-2,1]);%圧力係数のプロット
            app.solveElasticButton.Enable = "off";
        end

        % Value changed function: xlabelList
        function xlabelListValueChanged(app, event)
            plot(app.lsi.AERODATA{app.flowSelectDrop.Value}(:,app.xlabelList.Value),app.lsi.AERODATA{app.flowSelectDrop.Value}(:,app.ylabelList.Value),"-o","Parent",app.UIAxes_2);
            grid(app.UIAxes_2,"on");
            xlabel(app.UIAxes_2,app.xlabelList.Items{(app.xlabelList.Value == app.xlabelList.ItemsData)});
            ylabelName = [];
            for i = 1:numel(app.ylabelList.Value)
                ylabelName = strcat(ylabelName,app.ylabelList.Items{(app.ylabelList.Value(i) == app.ylabelList.ItemsData)}," ");
            end
            ylabel(app.UIAxes_2,ylabelName);
            title(app.UIAxes_2,app.flowSelectDrop.Items{(app.flowSelectDrop.Value == app.flowSelectDrop.ItemsData)});
        end

        % Value changed function: ylabelList
        function ylabelListValueChanged(app, event)
            plot(app.lsi.AERODATA{app.flowSelectDrop.Value}(:,app.xlabelList.Value),app.lsi.AERODATA{app.flowSelectDrop.Value}(:,app.ylabelList.Value),"-o","Parent",app.UIAxes_2);
            grid(app.UIAxes_2,"on");
            xlabel(app.UIAxes_2,app.xlabelList.Items{(app.xlabelList.Value == app.xlabelList.ItemsData)});
            ylabelName = [];
            for i = 1:numel(app.ylabelList.Value)
                ylabelName = strcat(ylabelName,app.ylabelList.Items{(app.ylabelList.Value(i) == app.ylabelList.ItemsData)}," ");
            end
            ylabel(app.UIAxes_2,ylabelName);
            title(app.UIAxes_2,app.flowSelectDrop.Items{(app.flowSelectDrop.Value == app.flowSelectDrop.ItemsData)});
        end

        % Button pushed function: readFemMeshButton
        function readFemMeshPushed(app, event)

            if isempty(app.FEAmeshEditField.Value)
                [file,locbuff] = uigetfile("*.msh","FEMメッシュを選択",app.location);
                if not(file == 0)
                    app.location = locbuff;
                    app.FEAmeshEditField.Value = fullfile(app.location,file);
                end
            end
            if not(isempty(app.FEAmeshEditField.Value))
                app.analysisProgressLabel.Text = "FEMメッシュ読込中";
                drawnow();
                [con,verts,femID] = guiFiles.readFemMesh(app.FEAmeshEditField.Value);
                app.lsi = app.lsi.setFemMesh(verts,con,femID,app.fem2aeroList.Value);%すべての空力メッシュIDとfemメッシュを関連付ける（第二引数省略
                app.lsi.plotFemMesh(app.UIAxes5,[]);
                uniqueID = unique(femID);
                materialTable = [];
                for i = 1:numel(uniqueID)
                    materialTable(i,:) = [uniqueID(i),1,90,1600,10];
                end
                [app.lsi,weight] = app.lsi.setFemMaterials(materialTable(:,1),materialTable(:,2)./1000,materialTable(:,3).*1e9,materialTable(:,4),materialTable(:,5));
                app.UITable.Data = [materialTable,weight(:)];
                scale = max(app.lsi.femtri.Points(:,2))-min(app.lsi.femtri.Points(:,2));
                y = linspace(min(app.lsi.femtri.Points(:,2))+scale*0.01,min(app.lsi.femtri.Points(:,2))+scale*0.99,40);
                EIx = zeros(1,numel(y));
                EIz = zeros(1,numel(y));
                Ax = zeros(1,numel(y));
                for i = 1:numel(y)
                    stiffness =app.lsi.calcCrossSectionalStiffness(y(i));
                    EIx(i) = stiffness.EIx;
                    EIz(i) = stiffness.EIz;
                    Ax(i) = stiffness.center(1);
                end
                yyaxis(app.UIAxes7,"left");xlim(app.UIAxes7,[min(app.lsi.femtri.Points(:,2)),max(app.lsi.femtri.Points(:,2))]);
                plot(app.UIAxes7,y,EIz,"bo-");hold(app.UIAxes7,"on");
                plot(app.UIAxes7,y,EIx,"bo--");hold(app.UIAxes7,"off");
                yyaxis(app.UIAxes7,"right");xlim(app.UIAxes7,[min(app.lsi.femtri.Points(:,2)),max(app.lsi.femtri.Points(:,2))]);
                plot(app.UIAxes7,y,Ax,"ro-");
                app.analysisProgressLabel.Text = "FEMメッシュ読込完了";
                drawnow();
                app.makeFemEquationButton.Enable = "on";
                app.femVinfFIeld.Enable = "on";
                app.selfLoadDrop.Enable = "on";
                app.elasticTimeField.Enable = "on";
                app.elasticdtField.Enable = "on";
                app.solveFemButton.Enable = "off";
            end

        end

        % Button pushed function: readFemMeshButton_2
        function readFemMeshButton_2Pushed(app, event)
            app.FEAmeshEditField.Value ='';
        end

        % Selection changed function: UITable
        function setFemMaterialButtonPushed(app, event)
            [app.lsi,weight] = app.lsi.setFemMaterials(app.UITable.Data(:,1)',app.UITable.Data(:,2)'./1000,app.UITable.Data(:,3)'.*1e9,app.UITable.Data(:,4)',app.UITable.Data(:,5)');
            app.UITable.Data = [app.UITable.Data(:,1:5),weight(:)];
            scale = max(app.lsi.femtri.Points(:,2))-min(app.lsi.femtri.Points(:,2));
            y = linspace(min(app.lsi.femtri.Points(:,2))+scale*0.01,min(app.lsi.femtri.Points(:,2))+scale*0.99,40);
            EIx = zeros(1,numel(y));
            EIz = zeros(1,numel(y));
            Ax = zeros(1,numel(y));
            for i = 1:numel(y)
                stiffness =app.lsi.calcCrossSectionalStiffness(y(i));
                EIx(i) = stiffness.EIx;
                EIz(i) = stiffness.EIz;
                Ax(i) = stiffness.center(1);
            end
            yyaxis(app.UIAxes7,"left")
            plot(app.UIAxes7,y,EIz,"bo-");hold(app.UIAxes7,"on");axis(app.UIAxes7,"auto");xlim(app.UIAxes7,[min(app.lsi.femtri.Points(:,2)),max(app.lsi.femtri.Points(:,2))]);
            plot(app.UIAxes7,y,EIx,"bo--");hold(app.UIAxes7,"off");
            yyaxis(app.UIAxes7,"right");
            plot(app.UIAxes7,y,Ax,"ro-");axis(app.UIAxes7,"auto");xlim(app.UIAxes7,[min(app.lsi.femtri.Points(:,2)),max(app.lsi.femtri.Points(:,2))]);
            app.solveFemButton.Enable = "off";
            app.solveElasticButton.Enable = "off";
        end

        % Button pushed function: makeFemEquationButton
        function makeFemEquationPushed(app, event)
            app.analysisProgressLabel.Text = "FEM行列作成中";
            drawnow();
            [app.lsi,weight] = app.lsi.setFemMaterials(app.UITable.Data(:,1)',app.UITable.Data(:,2)'./1000,app.UITable.Data(:,3)'.*1e9,app.UITable.Data(:,4)',app.UITable.Data(:,5)');
            app.UITable.Data = [app.UITable.Data(:,1:5),weight(:)];
            app.lsi = app.lsi.makeFemEquation();
            app.analysisProgressLabel.Text = "モード解析中";
            drawnow();
            app.lsi = app.lsi.femModalAnalysis(app.modalDimField.Value);
            app.analysisProgressLabel.Text = "FEM行列作成完了";
            drawnow();
            app.solveFemButton.Enable = "on";
            app.abortDrop.Enable = "on";
            app.modalNoDrop.Enable = "on";
            app.solveElasticButton.Enable = "on";
            app.modalNoDrop.Items = {};
            app.modalNoDrop.ItemsData = [];
            for i = 1:app.modalDimField.Value
                if i == 1
                    app.modalNoDrop.Items = [app.modalNoDrop.Items,"--"];
                    app.modalNoDrop.ItemsData = [app.modalNoDrop.ItemsData,-1];
                end
                app.modalNoDrop.Items = [app.modalNoDrop.Items,num2str(i)];
                app.modalNoDrop.ItemsData = [app.modalNoDrop.ItemsData,i];
            end
            app.modalNoDrop.Value = -1;

            app.DropDown.Items = {};
            app.DropDown.ItemsData = [];
            for i = 1:app.modalDimField.Value
                app.DropDown.Items = [app.DropDown.Items,strcat('z',num2str(i))];
                app.DropDown.ItemsData = [app.DropDown.ItemsData,i];
            end
            app.UITable3.Data(1,1:5) = [1,-5,0,5,3];
            app.UITable3_2.Data(1,1:5) = [1,-5,0,5,3];
        end

        % Value changed function: modalNoDrop
        function modalShowButtonPushed(app, event)
            if app.modalNoDrop.Value>0
                delta = app.lsi.femSol2Delta(app.lsi.femEigenVec(:,app.modalNoDrop.Value));
                scale = max(abs(vecnorm(delta(:,1:3),2,2)));
                app.lsi.plotFemMesh(app.UIAxes5,delta./scale);
                title(app.UIAxes5,sprintf("Mode No. %d (freq:%f)",app.modalNoDrop.Value,sqrt(app.lsi.femEigenVal(app.modalNoDrop.Value))/2/pi));
            else
                app.lsi.plotFemMesh(app.UIAxes5,[]);
                title(app.UIAxes5,sprintf("FEM Mesh Geometry"));
            end
        end

        % Button pushed function: solveFemButton
        function solveFemPushed(app, event)
            app.analysisProgressLabel.Text = "FEM解析中";
            drawnow();
            delta = app.lsi.solveModalFem(app.lsi.Cp{app.flowSelectDrop.Value}(:,app.alphaBetaDrop.Value).*app.femVinfFIeld.Value^2.*1.225.*0.5,app.selfLoadDrop.Value);
            app.lsi.plotFemMesh(app.UIAxes5,delta{1});
            modVerts = app.lsi.calcModifiedVerts(delta{1});
            lsi2 = app.lsi.setVerts(modVerts);
            app.analysisProgressLabel.Text = "FEM解析完了";
            drawnow();
        end

        % Value changed function: fem2aeroList
        function fem2aeroListValueChanged(app, event)
            app.makeFemEquationButton.Enable = "off";
            app.solveFemButton.Enable = "off";
            app.modalNoDrop.Enable = "off";
        end

        % Button pushed function: solveElasticButton
        function solveElasticButtonPushed(app, event)
            dt = app.elasticdtField.Value;
            t = 0;
            iterMax = round(app.elasticTimeField.Value/dt);

            tlog = 0;
            z{1} = zeros(size(app.lsi.femEigenVec,2),1);
            zdot{1} = zeros(size(app.lsi.femEigenVec,2),1);
            z{1}(1:app.lsi.modalSurrogateModel.nDesign,1) = app.UITable3.Data(:,3);
            zdot{1}(1:app.lsi.modalSurrogateModel.nDesign,1) = app.UITable3_2.Data(:,3);
            zlog = z{1};
            zdotlog = zdot{1};
            if app.abortDrop_2.Value ==1
                if isfield(app.var,"mFrame")
                    app.var = rmfield(app.var,"mFrame");
                end
            else
                app.solveElasticButton_2.Enable = "off";
            end
            app.var.mFrameRate = 1/dt;
            for i = 1:iterMax
                flowdata = app.lsi.AERODATA{app.flowSelectDrop.Value}(app.alphaBetaDrop.Value,1:4);
                [distLoad,df_dz,df_dzdot] = app.lsi.calcModalSurrogate(z,zdot,flowdata(3),flowdata(1),app.femVinfFIeld_2.Value);
                [z,zdot,delta,deltadot]  = app.lsi.solveModalAeroelastic([0,dt],z,zdot,distLoad,app.selfLoadDrop.Value,df_dz,df_dzdot);%初期値deltaとdeltadotから、tspan間での空力弾性応答を計算
                zlog = [zlog,z{1}];
                zdotlog = [zdotlog,zdot{1}];
                t = t + dt;
                tlog = [tlog,t];
                
                disp(full([z{1},zdot{1}]));
                modVerts = app.lsi.calcModifiedVerts(delta{1});
                Cp = distLoad./(0.5.*1.225.*app.femVinfFIeld_2.Value^2);
                wing2 = app.lsi.setVerts(modVerts);
                wing2.plotGeometry(app.UIAxes4,Cp,[-2,1]);
                if app.abortDrop_2.Value ==1
                    app.var.mFrame(i) = getframe(app.UIAxes4);
                    app.solveElasticButton_2.Enable = "on";
                else
                    app.solveElasticButton_2.Enable = "off";
                end
                title(app.UIAxes4,strcat(num2str(dt*i),"秒の変形と圧力分布"))
                app.lsi.plotFemMesh(app.UIAxes5,delta{1});
                plot(app.UIAxes4_2,tlog',zlog',"-o");
                ylabel(app.UIAxes4_2,"Z(modal)");
                xlabel(app.UIAxes4_2,"t(sec)");
                title(app.UIAxes4_2,"モード係数の時間履歴");
                for i = 1:numel(z{1})
                    legendLabel{i} = strcat("z",num2str(i));
                end
                legend(app.UIAxes4_2,legendLabel,"Box","off","Color","none");

                [eigFlutterb,freqb,etab] = app.lsi.solveModalFlutter(df_dz,df_dzdot,app.femVinfFIeld_2.Value);
                [S,I] = sort(freqb);
                freq = freqb(I);eta = etab(I);eigFlutter = eigFlutterb(I);
                flText = strcat("Flutter Analysis\n",...
                                sprintf("Vinf: %.4f \n",flowdata(3),app.femVinfFIeld_2.Value),...
                                sprintf("ALFA: %.2f, BETA :%.2f\n",flowdata(3),flowdata(1)),...
                                sprintf("MACH: %.2f, Re1e4: %.2f\n",flowdata(2),flowdata(4)*100),...
                                "Freq(Hz), Damp\n");
                for i = 1:numel(freq)
                    flText = strcat(flText,sprintf("%.4f,  %.4f\n",freq(i),eta(i)));
                end
                app.TextArea_2.Value = sprintf(flText);

                if app.abortDrop.Value == 1
                    break;
                end
            end
            app.abortDrop.Value = 0;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            flowdata = app.lsi.AERODATA{app.flowSelectDrop.Value}(app.alphaBetaDrop.Value,1:4);
            app.var.flutterflowdata = flowdata;
            app.analysisProgressLabel.Text = "空力補間曲面計算中";
            drawnow();
            app.lsi = app.lsi.makeModalSurrogate(app.UITable3.Data(:,2)',app.UITable3.Data(:,4)',app.UITable3.Data(:,5)',flowdata(2),flowdata(4)*1e6);
            app.analysisProgressLabel.Text = "空力補間曲面計算完了";
            drawnow();
            app.solveElasticButton.Enable = "on";
            app.abortDrop_2.Enable = "on";
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            flowdata = app.lsi.AERODATA{app.flowSelectDrop.Value}(app.alphaBetaDrop.Value,1:4);
            app.var.flutterflowdata = flowdata;
            z{1} = app.UITable3.Data(:,3);
            zdot{1} = app.UITable3_2.Data(:,3);
            [distLoad,df_dz,df_dzdot] = app.lsi.calcModalSurrogate(z,zdot,flowdata(3),flowdata(1),app.femVinfFIeld_2.Value);
            Cp = distLoad./(0.5.*1.225.*app.femVinfFIeld_2.Value^2);
            wing2 = app.lsi.setVerts(app.lsi.calcModifiedVerts(app.lsi.femSol2Delta(app.lsi.femEigenVec(:,1:app.lsi.modalSurrogateModel.nDesign)*z{1})));
            wing2.plotGeometry(app.UIAxes5_2,Cp,[-2,1]);
            [eigFlutterb,freqb,etab] = app.lsi.solveModalFlutter(df_dz,df_dzdot,app.femVinfFIeld_2.Value);
            [S,I] = sort(freqb);
            freq = freqb(I);eta = etab(I);eigFlutter = eigFlutterb(I);
            flText = strcat("Flutter Analysis\n",...
                            sprintf("Vinf: %.4f \n",flowdata(3),app.femVinfFIeld_2.Value),...
                            sprintf("ALFA: %.2f, BETA :%.2f\n",flowdata(3),flowdata(1)),...
                            sprintf("MACH: %.2f, Re1e4: %.2f\n",flowdata(2),flowdata(4)*100),...
                            "Freq(Hz), Damp\n");
            for i = 1:numel(freq)
                flText = strcat(flText,sprintf("%.4f,  %.4f\n",freq(i),eta(i)));
            end
            app.TextArea_2.Value = sprintf(flText);
            app.solveElasticButton.Enable = "on";
        end

        % Button pushed function: dynCoefButton
        function dynCoefButtonPushed(app, event)
            app.lsi = app.lsi.setREFS(app.SREFEditField.Value,app.BREFEditField.Value,app.CREFEditField.Value,[app.XREFEditField.Value,app.YREFEditField.Value,app.ZREFEditField.Value]); %基準面積 基準長の設定
            flowdata = app.lsi.AERODATA{app.flowSelectDrop.Value}(app.alphaBetaDrop.Value,[1:5,19,11,6]);
            %flowdata = [0,0,0,0];
            app.analysisProgressLabel.Text = "動安定微係数計算中";
            drawnow();
            app.lsi = app.lsi.setUNLSISettings("propCalcFlag",app.ADFlagDrop.Value);
            app.lsi = app.lsi.calcDynCoef(flowdata(3),flowdata(1),flowdata(2),flowdata(4)*1e6); %動安定微係数を計算（deflDerivFlagを1にすると動翼を微小量動かして数値微分を取ってくれる）
            app.analysisProgressLabel.Text = "動安定微係数計算完了";
            drawnow();
            Cxu = -2*flowdata(7);
            Czu = 0;
            Cmu = 0;
            [dyncoef,dynCoefStruct] = app.lsi.getDYNCOEF(flowdata(3),flowdata(1),flowdata(2),flowdata(4)*1e6);
            fcText = strcat("Flow Condition\n",...
                            "ALFA\t",num2str(flowdata(3)),"\n",...
                            "BETA\t",num2str(flowdata(1)),"\n",...
                            "MACH\t",num2str(flowdata(2)),"\n",...
                            "Re1e4\t",num2str(flowdata(4)*100),"\n");
            lgText = strcat("\nNonDim Longuitude Coefficient\n",...
                            "XREF\t\t",num2str(app.lsi.XYZREF(1)),"\n",...
                            "XREF-Trim\t",num2str(-flowdata(6)/flowdata(5)*app.lsi.CREF),"\n",...
                            "Stab Limit\t",num2str(dynCoefStruct{1}.Cma/dynCoefStruct{1}.Cza*app.lsi.CREF),"\n",...
                            "Cxu\t",num2str(Cxu),"\n",...
                            "Czu\t",num2str(Czu),"\n",...
                            "Cmu\t",num2str(Cmu),"\n",...
                            "Cxa\t",num2str(dynCoefStruct{1}.Cxa),"\n",...
                            "Cza\t",num2str(dynCoefStruct{1}.Cza),"\n",...
                            "Cma\t",num2str(dynCoefStruct{1}.Cma),"\n",...
                            "Cxq\t",num2str(dynCoefStruct{1}.Cxq),"\n",...
                            "Czq\t",num2str(dynCoefStruct{1}.Czq),"\n",...
                            "Cmq\t",num2str(dynCoefStruct{1}.Cmq),"\n");


            if app.lsi.halfmesh == 1
                if size(dyncoef{1},2)>6
                    nddCoefText = strcat("\nNonDim Actuator Coefficient\n");
                    for i = 1:size(dyncoef{1},2)-6
                        nddCoefText = strcat(nddCoefText,"Cxf",num2str(i),"\t\t",num2str(dyncoef{1}(1,i+6)),"\n",...
                                                         "Czf",num2str(i),"\t\t",num2str(dyncoef{1}(3,i+6)),"\n",...
                                                         "Cmf",num2str(i),"\t\t",num2str(dyncoef{1}(5,i+6)),"\n");
                    end
                else
                    nddCoefText = "";
                end
                app.TextArea.Value = sprintf(strcat(fcText,lgText,nddCoefText));
            else
                ltText = strcat("\nNonDim Lattude Coefficient\n",...
                            "Cyb\t",num2str(dynCoefStruct{1}.Cyb),"\n",...
                            "Clb\t",num2str(dynCoefStruct{1}.Clb),"\n",...
                            "Cnb\t",num2str(dynCoefStruct{1}.Cnb),"\n",...
                            "Cyp\t",num2str(dynCoefStruct{1}.Cyp),"\n",...
                            "Clp\t",num2str(dynCoefStruct{1}.Clp),"\n",...
                            "Cnp\t",num2str(dynCoefStruct{1}.Cnp),"\n", ...
                            "Cyr\t",num2str(dynCoefStruct{1}.Cyr),"\n",...
                            "Clr\t",num2str(dynCoefStruct{1}.Clr),"\n",...
                            "Cnr\t",num2str(dynCoefStruct{1}.Cnr),"\n");
                if size(dyncoef{1},2)>6
                    nddCoefText = strcat("\nNonDim Actuator Coefficient\n");
                    for i = 1:size(dyncoef{1},2)-6
                        nddCoefText = strcat(nddCoefText,"Cxf",num2str(i),"\t\t",num2str(dyncoef{1}(1,i+6)),"\n",...
                                                         "Cyf",num2str(i),"\t\t",num2str(dyncoef{1}(2,i+6)),"\n",...
                                                         "Czf",num2str(i),"\t\t",num2str(dyncoef{1}(3,i+6)),"\n",...
                                                         "Clf",num2str(i),"\t\t",num2str(dyncoef{1}(4,i+6)),"\n",...
                                                         "Cmf",num2str(i),"\t\t",num2str(dyncoef{1}(5,i+6)),"\n",...
                                                         "Cnf",num2str(i),"\t\t",num2str(dyncoef{1}(6,i+6)),"\n");
                    end
                else
                    nddCoefText = "";
                end
                app.TextArea.Value = sprintf(strcat(fcText,lgText,ltText,nddCoefText));
            end
            app.readMassPropButton.Enable = "on";
            app.readMassPropButton_2.Enable = "on";
           
        end

        % Button pushed function: readMassPropButton
        function readMassPropButtonPushed(app, event)
            [file,locbuff] = uigetfile("*.txt","openVSP質量特性ファイルを選択",app.location);
            if not(file == 0)
                app.location = locbuff;
                app.massPropField.Value = fullfile(app.location,file);
                app.dimAnalysisButton.Enable = "on";
                app.dimAnalysisButtonPushed();
            else
                app.dimAnalysisButton.Enable = "off";
            end
        end

        % Button pushed function: readMassPropButton_2
        function readMassPropButton_2Pushed(app, event)
            app.massPropField.Value = "";
        end

        % Button pushed function: dimAnalysisButton
        function dimAnalysisButtonPushed(app, event)
            flowdata = app.lsi.AERODATA{app.flowSelectDrop.Value}(app.alphaBetaDrop.Value,[1:5,19,11,6]);
            [mass,cgPoint,Inatia] = guiFiles.readMassPropResult(app.massPropField.Value);
            UREF = app.dynVinfFIeld.Value;
            [modal,dyncoef,dynCoefStruct] = app.lsi.getModal(flowdata(3),flowdata(1),flowdata(2),flowdata(4)*1e6,UREF,mass,Inatia);
            Cxu = -2*flowdata(7);
            Czu = 0;
            Cmu = 0;
            fcText = strcat("Flow Condition\n",...
                        "ALFA\t",num2str(flowdata(3)),"\n",...
                        "BETA\t",num2str(flowdata(1)),"\n",...
                        "MACH\t",num2str(flowdata(2)),"\n",...
                        "Re1e4\t",num2str(flowdata(4)*100),"\n",...
                        "UREF\t",num2str(UREF),"\n");
            lgText = strcat("\nNonDim Longuitude Coefficient\n",...
                        "XCG\t\t\t",num2str(cgPoint(1)),"\n",...
                        "XREF\t\t",num2str(app.lsi.XYZREF(1)),"\n",...
                        "XREF-Trim\t",num2str(-flowdata(6)/flowdata(5)*app.lsi.CREF),"\n",...
                        "Stab Limit\t",num2str(dynCoefStruct{1}.Cma/dynCoefStruct{1}.Cza*app.lsi.CREF),"\n",...
                        "Cxu\t",num2str(Cxu),"\n",...
                        "Czu\t",num2str(Czu),"\n",...
                        "Cmu\t",num2str(Cmu),"\n",...
                        "Cxa\t",num2str(dynCoefStruct{1}.Cxa),"\n",...
                        "Cza\t",num2str(dynCoefStruct{1}.Cza),"\n",...
                        "Cma\t",num2str(dynCoefStruct{1}.Cma),"\n",...
                        "Cxq\t",num2str(dynCoefStruct{1}.Cxq),"\n",...
                        "Czq\t",num2str(dynCoefStruct{1}.Czq),"\n",...
                        "Cmq\t",num2str(dynCoefStruct{1}.Cmq),"\n");
            Xu = app.lsi.settingUNLSI.rho*UREF*app.lsi.SREF/(2*mass)*(Cxu);
            Zu = app.lsi.settingUNLSI.rho*UREF*app.lsi.SREF/(2*mass)*(-2*flowdata(8));
            Mu = 0;
            Xa = app.lsi.settingUNLSI.rho*UREF^2*app.lsi.SREF/(2*mass)*dynCoefStruct{1}.Cxa;
            Za = app.lsi.settingUNLSI.rho*UREF^2*app.lsi.SREF/(2*mass)*dynCoefStruct{1}.Cza;
            Ma = app.lsi.settingUNLSI.rho*UREF^2*app.lsi.SREF*app.lsi.CREF/(2*Inatia(2,2))*dynCoefStruct{1}.Cma;
            Xq = 0;
            Zq = app.lsi.settingUNLSI.rho*UREF*app.lsi.SREF*app.lsi.CREF/(4*mass)*dynCoefStruct{1}.Czq;
            Mq = app.lsi.settingUNLSI.rho*UREF*app.lsi.SREF*app.lsi.CREF^2/(4*Inatia(2,2))*dynCoefStruct{1}.Cmq;
            dimlgText = strcat("\nLonguitude Coefficient\n",...
                        "Xu\t",num2str(Xa),"\n",...
                        "Zu\t",num2str(Za),"\n",...
                        "Mu\t",num2str(Ma),"\n",...
                        "Xa\t",num2str(Xa),"\n",...
                        "Za\t",num2str(Za),"\n",...
                        "Ma\t",num2str(Ma),"\n",...
                        "Xq\t",num2str(Xq),"\n",...
                        "Zq\t",num2str(Zq),"\n",...
                        "Mq\t",num2str(Mq),"\n");
            lgModalText = strcat("\nLonguitude Modal Data\n",...
                        "\tshort Period\n",...
                        "\t\tOmega n \t",num2str(modal.shortPriod.omegan),"\n",...
                        "\t\tEta\t",num2str(modal.shortPriod.eta),"\n");
            theta0 = 0;
            A = [   Xu,    Xa,     0.0,    -9.8*cos(theta0), 0.0;
                 Zu/UREF, Za/UREF, Zq/UREF+1, -9.8*sin(theta0)/UREF, 0.0;
                    Mu,    Ma,      Mq,                 0.0, 0.0;
                     0,     0,       1,                 0.0, 0.0;
                     0,   -UREF,     0.0,                  UREF, 0.0];

            longEig = eig(A);
            % 極の実部と虚部を抽出
            pole_real = real(longEig);
            pole_imag = imag(longEig);
            
            % 極座標プロットの作成
            plot(pole_real, pole_imag, 'x', 'MarkerSize', 10, 'LineWidth', 2,'Parent',app.UIAxes6); % 極をプロット
            hold(app.UIAxes6,"off");
            % タイトルとラベルの設定
            title(app.UIAxes6,'縦系の極配置');
            xlabel(app.UIAxes6,'実部');
            ylabel(app.UIAxes6,'虚部');
            % メジャーグリッドとマイナーグリッドを表示
            grid(app.UIAxes6,"on");
            grid(app.UIAxes6,"minor");
            
            % 軸の表示範囲を調整
            axis(app.UIAxes6,"tight");
            
            % グリッドのスタイルをカスタマイズ
            ax = app.UIAxes6;
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            ax.MinorGridLineStyle = ':';
            ax.GridLineStyle = '--';
            ax.GridColor = [0.2, 0.2, 0.2];
            ax.GridAlpha = 0.6;
            ax.MinorGridColor = [0.8, 0.8, 0.8];
            ax.MinorGridAlpha = 0.5;
            
            % 原点を基準に交差線を追加
            hold(ax,"on");
            line(ax,get(ax, 'XLim'), [0 0], 'Color', 'k', 'LineStyle', '--'); % x軸
            line(ax,[0 0], get(ax, 'YLim'), 'Color', 'k', 'LineStyle', '--'); % y軸
            hold(ax,"off");
            

            lgMatText = strcat("\nLatitude Matrix\n",...
                        "theta0 = 0.0;\n",...
                        "xlong = {u,alpha,q,theta,alt};\n",...
                        "Along = \n",...
                            "{",sprintf("%.4f,%.4f,%.4f,%.4f,%.4f,\n",A(1,1),A(1,2),A(1,3),A(1,4),A(1,5)),...
                                sprintf("%.4f,%.4f,%.4f,%.4f,%.4f,\n",A(2,1),A(2,2),A(2,3),A(2,4),A(2,5)),...
                                sprintf("%.4f,%.4f,%.4f,%.4f,%.4f,\n",A(3,1),A(3,2),A(3,3),A(3,4),A(3,5)),...
                                sprintf("%.4f,%.4f,%.4f,%.4f,%.4f,\n",A(4,1),A(4,2),A(4,3),A(4,4),A(4,5)),...
                                sprintf("%.4f,%.4f,%.4f,%.4f,%.4f",A(5,1),A(5,2),A(5,3),A(5,4),A(5,5)),"};","\n");
            if app.lsi.halfmesh == 1
                if size(dyncoef{1},2)>6
                    nddCoefText = strcat("\nNonDim Actuator Coefficient\n");
                    ddCoefText = strcat("\nActuator Coefficient\n");
                    for i = 1:size(dyncoef{1},2)-6
                        nddCoefText = strcat(nddCoefText,"Cxf",num2str(i),"\t\t",num2str(dyncoef{1}(1,i+6)),"\n",...
                                                         "Czf",num2str(i),"\t\t",num2str(dyncoef{1}(3,i+6)),"\n",...
                                                         "Cmf",num2str(i),"\t\t",num2str(dyncoef{1}(5,i+6)),"\n");
                        ddCoefText = strcat(ddCoefText,"Xf",num2str(i),"\t\t",num2str(app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF*app.lsi.BREF/(2*mass)*dyncoef{1}(1,i+6)),"\n",...
                                                        "Zf",num2str(i),"\t\t",num2str(app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF*app.lsi.BREF/(2*mass)*dyncoef{1}(3,i+6)),"\n",...
                                                        "Mf",num2str(i),"\t\t",num2str(app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF*app.lsi.BREF/(2*Inatia(2,2))*dyncoef{1}(5,i+6)),"\n");
                    end
                else
                    nddCoefText = "";
                    ddCoefText = "";
                end
                app.TextArea.Value = sprintf(strcat(fctext,lgText,nddCoefText,dimlgText,ddCoefText,lgModalText,lgMatText));
            else
                ltText = strcat("\nNonDim Lattude Coefficient\n",...
                        "Cyb\t",num2str(dynCoefStruct{1}.Cyb),"\n",...
                        "Clb\t",num2str(dynCoefStruct{1}.Clb),"\n",...
                        "Cnb\t",num2str(dynCoefStruct{1}.Cnb),"\n",...
                        "Cyp\t",num2str(dynCoefStruct{1}.Cyp),"\n",...
                        "Clp\t",num2str(dynCoefStruct{1}.Clp),"\n",...
                        "Cnp\t",num2str(dynCoefStruct{1}.Cnp),"\n", ...
                        "Cyr\t",num2str(dynCoefStruct{1}.Cyr),"\n",...
                        "Clr\t",num2str(dynCoefStruct{1}.Clr),"\n",...
                        "Cnr\t",num2str(dynCoefStruct{1}.Cnr),"\n");
                Yb = app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF/(2*mass)*dynCoefStruct{1}.Cyb;
                Lb = app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF*app.lsi.BREF/(2*Inatia(1,1))*dynCoefStruct{1}.Clb;
                Nb = app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF*app.lsi.BREF/(2*Inatia(3,3))*dynCoefStruct{1}.Cnb;
                Yp = app.lsi.settingUNLSI.rho*UREF*app.lsi.SREF*app.lsi.BREF/(4*mass)*dynCoefStruct{1}.Cyp;
                Lp = app.lsi.settingUNLSI.rho*UREF*app.lsi.SREF*app.lsi.BREF*app.lsi.BREF/(4*Inatia(1,1))*dynCoefStruct{1}.Clp;
                Np = app.lsi.settingUNLSI.rho*UREF*app.lsi.SREF*app.lsi.BREF*app.lsi.BREF/(4*Inatia(3,3))*dynCoefStruct{1}.Cnp;
                Yr = app.lsi.settingUNLSI.rho*UREF*app.lsi.SREF*app.lsi.BREF/(4*mass)*dynCoefStruct{1}.Cyr;
                Lr = app.lsi.settingUNLSI.rho*UREF*app.lsi.SREF*app.lsi.BREF*app.lsi.BREF/(4*Inatia(1,1))*dynCoefStruct{1}.Clr;
                Nr = app.lsi.settingUNLSI.rho*UREF*app.lsi.SREF*app.lsi.BREF*app.lsi.BREF/(4*Inatia(3,3))*dynCoefStruct{1}.Cnr;
                dimltText = strcat("\nLatitude Coefficient\n",...
                            "Yb\t",num2str(Yb),"\n",...
                            "Lb\t",num2str(Lb),"\n",...
                            "Nb\t",num2str(Nb),"\n",...
                            "Yp\t",num2str(Yp),"\n",...
                            "Lp\t",num2str(Lp),"\n",...
                            "Np\t",num2str(Np),"\n",...
                            "Yr\t",num2str(Yr),"\n",...
                            "Lr\t",num2str(Lr),"\n",...
                            "Nr\t",num2str(Nr),"\n");
                ltModalText = strcat("\nLatitude Modal Data\n",...
                            "\tRoll Mode\n",...
                            "\t\tTc\t",num2str(modal.roll.T),"\n",...
                            "\tDatchRoll Mode\n",...
                            "\t\tOmega n\t",num2str(modal.datchRoll.omegan),"\n",...
                            "\t\tEta\t",num2str(modal.datchRoll.eta),"\n",...
                            "\tSpiral Mode\n",...
                            "\t\tTc\t",num2str(modal.spiral.T),"\n");
                theta0 = 0;
                A = [Yb/UREF, Yp/UREF,    Yr/UREF-1, 9.8*cos(theta0)/UREF, 0;
                     Lb,    Lp,         Lr,             0,    0;
                     Nb,    Np,         Nr,             0,    0;
                      0,        1, tan(theta0),         0,    0;
                      0,        0, sec(theta0),         0,    0];
                latEig = eig(A);
                % 極の実部と虚部を抽出
                pole_real = real(latEig);
                pole_imag = imag(latEig);
                
                % 極座標プロットの作成
                plot(pole_real, pole_imag, 'x', 'MarkerSize', 10, 'LineWidth', 2,'Parent',app.UIAxes6_2); % 極をプロット
                hold(app.UIAxes6_2,"off");
                % タイトルとラベルの設定
                title(app.UIAxes6_2,'横系の極配置');
                xlabel(app.UIAxes6_2,'実部');
                ylabel(app.UIAxes6_2,'虚部');
                % メジャーグリッドとマイナーグリッドを表示
                grid(app.UIAxes6_2,"on");
                grid(app.UIAxes6_2,"minor");
                
                % 軸の表示範囲を調整
                axis(app.UIAxes6_2,"tight");
                
                % グリッドのスタイルをカスタマイズ
                ax = app.UIAxes6_2;
                ax.XGrid = 'on';
                ax.YGrid = 'on';
                ax.MinorGridLineStyle = ':';
                ax.GridLineStyle = '--';
                ax.GridColor = [0.2, 0.2, 0.2];
                ax.GridAlpha = 0.6;
                ax.MinorGridColor = [0.8, 0.8, 0.8];
                ax.MinorGridAlpha = 0.5;
                
                % 原点を基準に交差線を追加
                hold(ax,"on");
                line(ax,get(ax, 'XLim'), [0 0], 'Color', 'k', 'LineStyle', '--'); % x軸
                line(ax,[0 0], get(ax, 'YLim'), 'Color', 'k', 'LineStyle', '--'); % y軸
                hold(ax,"off");

                ltMatText = strcat("\nLatitude Matrix\n",...
                            "theta0 = 0.0;\n",...
                            "xlat = {beta,p,r,phi,psi};\n",...
                            "Alat = \n",...
                            "{",sprintf("%.4f,%.4f,%.4f,%.4f,%.4f,\n",A(1,1),A(1,2),A(1,3),A(1,4),A(1,5)),...
                                sprintf("%.4f,%.4f,%.4f,%.4f,%.4f,\n",A(2,1),A(2,2),A(2,3),A(2,4),A(2,5)),...
                                sprintf("%.4f,%.4f,%.4f,%.4f,%.4f,\n",A(3,1),A(3,2),A(3,3),A(3,4),A(3,5)),...
                                sprintf("%.4f,%.4f,%.4f,%.4f,%.4f,\n",A(4,1),A(4,2),A(4,3),A(4,4),A(4,5)),...
                                sprintf("%.4f,%.4f,%.4f,%.4f,%.4f",A(5,1),A(5,2),A(5,3),A(5,4),A(5,5)),"};","\n");
                if size(dyncoef{1},2)>6
                    nddCoefText = strcat("\nNonDim Actuator Coefficient\n");
                    for i = 1:size(dyncoef{1},2)-6
                        nddCoefText = strcat(nddCoefText,"Cxf",num2str(i),"\t\t",num2str(dyncoef{1}(1,i+6)),"\n",...
                                                         "Cyf",num2str(i),"\t\t",num2str(dyncoef{1}(2,i+6)),"\n",...
                                                         "Czf",num2str(i),"\t\t",num2str(dyncoef{1}(3,i+6)),"\n",...
                                                         "Clf",num2str(i),"\t\t",num2str(dyncoef{1}(4,i+6)),"\n",...
                                                         "Cmf",num2str(i),"\t\t",num2str(dyncoef{1}(5,i+6)),"\n",...
                                                         "Cnf",num2str(i),"\t\t",num2str(dyncoef{1}(6,i+6)),"\n");
                    end
                    ddCoefText = strcat("\nActuator Coefficient\n");
                    for i = 1:size(dyncoef{1},2)-6
                        ddCoefText = strcat(ddCoefText,"Xf",num2str(i),"\t\t",num2str(app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF*app.lsi.BREF/(2*mass)*dyncoef{1}(1,i+6)),"\n",...
                                                         "Yf",num2str(i),"\t\t",num2str(app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF*app.lsi.BREF/(2*mass)*dyncoef{1}(2,i+6)),"\n",...
                                                         "Zf",num2str(i),"\t\t",num2str(app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF*app.lsi.BREF/(2*mass)*dyncoef{1}(3,i+6)),"\n",...
                                                         "Lf",num2str(i),"\t\t",num2str(app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF*app.lsi.BREF/(2*Inatia(1,1))*dyncoef{1}(4,i+6)),"\n",...
                                                         "Mf",num2str(i),"\t\t",num2str(app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF*app.lsi.BREF/(2*Inatia(2,2))*dyncoef{1}(5,i+6)),"\n",...
                                                         "Nf",num2str(i),"\t\t",num2str(app.lsi.settingUNLSI.rho*UREF*UREF*app.lsi.SREF*app.lsi.BREF/(2*Inatia(3,3))*dyncoef{1}(6,i+6)),"\n");
                    end
                else
                    nddCoefText = "";
                    ddCoefText = "";
                end
                app.TextArea.Value = sprintf(strcat(fcText,lgText,ltText,nddCoefText,dimlgText,dimltText,ddCoefText,lgModalText,ltModalText,lgMatText,ltMatText));
            end
        end

        % Value changed function: propNoField
        function propNoFieldValueChanged(app, event)

            uniqueID = unique(app.lsi.surfID);
            if numel(uniqueID)>1 && app.propNoField.Value > 0
                app.propSelDrop.Items = {};
                app.propSelDrop.ItemsData = [];
                for i = 1:app.propNoField.Value
                    app.propSelDrop.Items = [app.propSelDrop.Items,strcat("prop",num2str(i))];
                    app.propSelDrop.ItemsData = [app.propSelDrop.ItemsData,i];
                    app.lsi.prop{i} = [];
                end
                app.propIDField.Items = {};
                app.propIDField.ItemsData = [];
                app.execAnalysesButton.Enable = "off";
                if app.propNoField.Value > 0
                    
                    for i = 1:numel(uniqueID)
                        app.propIDField.Items = [app.propIDField.Items,num2str(uniqueID(i))];
                        app.propIDField.ItemsData = [app.propIDField.ItemsData,i];
                    end
                end
                app.lsi.prop = {};
                app.CTEditField.Value = 0;
                app.CPEditField.Value = 0;
                app.RPMEditField.Value = 0;
                app.propSymField.Value = 0;
                app.propDiaField.Value = 0;
                app.propSymField.Enable = "off";
                app.propDiaField.Enable = "off";
                app.CTEditField.Enable = "off";
                app.CPEditField.Enable = "off";
                app.RPMEditField.Enable = "off";
            else
                app.lsi.prop = {};
                app.propNoField.Value = 0;
                app.propSelDrop.Items = {};
                app.propSelDrop.ItemsData = [];
                app.propIDField.Items = {};
                app.propIDField.ItemsData = [];
                app.CTEditField.Value = 0;
                app.CPEditField.Value = 0;
                app.RPMEditField.Value = 0;
                app.propSymField.Value = 0;
                app.propDiaField.Value = 0;
                app.propSymField.Enable = "off";
                app.propDiaField.Enable = "off";
                app.CTEditField.Enable = "off";
                app.CPEditField.Enable = "off";
                app.RPMEditField.Enable = "off";

            end
        end

        % Value changed function: propSelDrop
        function propSelChanged(app, event)
            try
                app.propIDField.Value = app.lsi.prop{app.propSelDrop.Value}.ID;
                app.CTEditField.Value = app.lsi.prop{app.propSelDrop.Value}.CT;
                app.CPEditField.Value = app.lsi.prop{app.propSelDrop.Value}.CP;
                app.RPMEditField.Value = app.lsi.prop{app.propSelDrop.Value}.rpm;
                app.propSymField.Value = app.lsi.prop{app.propSelDrop.Value}.XZsliced;
                app.propDiaField.Value = app.lsi.prop{app.propSelDrop.Value}.diameter;
            catch
                app.propIDField.Value = [];
                app.CTEditField.Value = 0.4;
                app.CPEditField.Value = 0.6;
                app.RPMEditField.Value = 2000;
                app.propSymField.Value = 0;
                app.propDiaField.Value = 0.1;
            end
            
            uniqueID = unique(app.lsi.surfID);
            if not(isempty(app.propIDField.Value)) && numel(app.propIDField.Value) < numel(uniqueID)
                if isempty(app.lsi.prop{app.propSelDrop.Value})
                    app.lsi = app.lsi.setProp(app.propSelDrop.Value,app.propIDField.Value,0.1,0);
                    app.lsi = app.lsi.setPropState(app.propSelDrop.Value,0.4,0.6,2000); %プロペラの回転状況を指定
                else
                    app.lsi = app.lsi.setProp(app.propSelDrop.Value,app.propIDField.Value,app.propDiaField.Value,app.propSymField.Value);
                    app.lsi = app.lsi.setPropState(app.propSelDrop.Value,app.CTEditField.Value,app.CPEditField.Value,app.RPMEditField.Value); %プロペラの回転状況を指定
                end
                app.propSymField.Enable = "on";
                app.propDiaField.Enable = "on";
                app.CTEditField.Enable = "on";
                app.CPEditField.Enable = "on";
                app.RPMEditField.Enable = "on";
            else
                app.lsi.prop{app.propSelDrop.Value} = [];
                for i = 1:numel(uniqueID)
                    app.lsi = app.lsi.setPanelType(uniqueID(i),"body");
                end
                for i = 1:numel(app.lsi.prop)
                    if not(isempty(app.lsi.prop{i}))
                        app.lsi = app.lsi.setPanelType(app.lsi.prop{i}.ID,"prop");
                    end
                end
                app.CTEditField.Value = 0;
                app.CPEditField.Value = 0;
                app.RPMEditField.Value = 0;
                app.propSymField.Value = 0;
                app.propDiaField.Value = 0;
                app.execAnalysesButton.Enable = "off";
                app.propSymField.Enable = "off";
                app.propDiaField.Enable = "off";
                app.CTEditField.Enable = "off";
                app.CPEditField.Enable = "off";
                app.RPMEditField.Enable = "off";
            end
        end

        % Value changed function: propDiaField, propIDField, propSymField
        function propChanged(app, event)
            uniqueID = unique(app.lsi.surfID);
            if not(isempty(app.propIDField.Value)) && numel(app.propIDField.Value) < numel(uniqueID)
                if not(isempty(app.lsi.prop))
                    if isempty(app.lsi.prop{app.propSelDrop.Value})
                        app.lsi = app.lsi.setProp(app.propSelDrop.Value,app.propIDField.Value,0.1,0);
                        app.lsi = app.lsi.setPropState(app.propSelDrop.Value,0.4,0.6,2000); %プロペラの回転状況を指定
                    else
                        app.lsi = app.lsi.setProp(app.propSelDrop.Value,app.propIDField.Value,app.propDiaField.Value,app.propSymField.Value);
                        app.lsi = app.lsi.setPropState(app.propSelDrop.Value,app.CTEditField.Value,app.CPEditField.Value,app.RPMEditField.Value); %プロペラの回転状況を指定
                    end
                else
                    app.lsi = app.lsi.setProp(app.propSelDrop.Value,app.propIDField.Value,0.1,0);
                    app.lsi = app.lsi.setPropState(app.propSelDrop.Value,0.4,0.6,2000); %プロペラの回転状況を指定
                end
                app.execAnalysesButton.Enable = "off";
                app.propSymField.Enable = "on";
                app.propDiaField.Enable = "on";
                app.CTEditField.Enable = "on";
                app.CPEditField.Enable = "on";
                app.RPMEditField.Enable = "on";
                app.propIDField.Value = app.lsi.prop{app.propSelDrop.Value}.ID;
                app.CTEditField.Value = app.lsi.prop{app.propSelDrop.Value}.CT;
                app.CPEditField.Value = app.lsi.prop{app.propSelDrop.Value}.CP;
                app.RPMEditField.Value = app.lsi.prop{app.propSelDrop.Value}.rpm;
                app.propSymField.Value = app.lsi.prop{app.propSelDrop.Value}.XZsliced;
                app.propDiaField.Value = app.lsi.prop{app.propSelDrop.Value}.diameter;
            else
                app.lsi.prop{app.propSelDrop.Value} = [];
                for i = 1:numel(uniqueID)
                    app.lsi = app.lsi.setPanelType(uniqueID(i),"body");
                end
                for i = 1:numel(app.lsi.prop)
                    if not(isempty(app.lsi.prop{i}))
                        app.lsi = app.lsi.setPanelType(app.lsi.prop{i}.ID,"prop");
                    end
                end
                app.execAnalysesButton.Enable = "off";
                app.CTEditField.Value = 0;
                app.CPEditField.Value = 0;
                app.RPMEditField.Value = 0;
                app.propSymField.Value = 0;
                app.propDiaField.Value = 0;
                app.propSymField.Enable = "off";
                app.propDiaField.Enable = "off";
                app.CTEditField.Enable = "off";
                app.CPEditField.Enable = "off";
                app.RPMEditField.Enable = "off";
            end
        end

        % Value changed function: CPEditField, CTEditField, RPMEditField
        function PropStateChanged(app, event)
            uniqueID = unique(app.lsi.surfID);
            if not(isempty(app.propIDField.Value)) && numel(app.propIDField.Value) < numel(uniqueID)
                if isempty(app.lsi.prop{app.propSelDrop.Value})
                    %app.lsi = app.lsi.setProp(app.propSelDrop.Value,app.propIDField.Value,0.1,0);
                    app.lsi = app.lsi.setPropState(app.propSelDrop.Value,0.4,0.6,2000); %プロペラの回転状況を指定
                else
                    %app.lsi = app.lsi.setProp(app.propSelDrop.Value,app.propIDField.Value,app.propDiaField.Value,app.propSymField.Value);
                    app.lsi = app.lsi.setPropState(app.propSelDrop.Value,app.CTEditField.Value,app.CPEditField.Value,app.RPMEditField.Value); %プロペラの回転状況を指定
                end
                app.propSymField.Enable = "on";
                app.propDiaField.Enable = "on";
                app.CTEditField.Enable = "on";
                app.CPEditField.Enable = "on";
                app.RPMEditField.Enable = "on";
            else
                app.lsi.prop{app.propSelDrop.Value} = [];
                app.CTEditField.Value = 0;
                app.CPEditField.Value = 0;
                app.RPMEditField.Value = 0;
                app.propSymField.Value = 0;
                app.propDiaField.Value = 0;
                app.propSymField.Enable = "off";
                app.propDiaField.Enable = "off";
                app.CTEditField.Enable = "off";
                app.CPEditField.Enable = "off";
                app.RPMEditField.Enable = "off";
            end
        end

        % Value changed function: actNoField
        function actNoFieldValueChanged(app, event)
            app.actSelDrop.Items = {};
            app.actSelDrop.ItemsData = [];
            uniqueID = unique(app.lsi.surfID);
            for i = 1:app.actNoField.Value
                app.actSelDrop.Items = [app.actSelDrop.Items,strcat("defl",num2str(i))];
                app.actSelDrop.ItemsData = [app.actSelDrop.ItemsData,i];
                app.lsi = app.lsi.setDeflGroup(app.actSelDrop.ItemsData(i),app.actSelDrop.Items(app.actSelDrop.Value),uniqueID(1),0); % ID6番7番をgain[1,1](軸が同じなら同じ方向）に動かすことをelevatorとして登録(動翼ID1番） ;%デフォルト値を書き込む
            end
            
            %IDフィールドの設定
            
            app.actIDField.Items = {};
            app.actIDField.ItemsData = [];
            app.actGainTable.Data = [];
            if app.actNoField.Value>0
                for i = 1:numel(uniqueID)
                    app.actIDField.Items = [app.actIDField.Items,num2str(uniqueID(i))];
                    app.actIDField.ItemsData = [app.actIDField.ItemsData,uniqueID(i)];
                end
                app.actIDField.Value = app.lsi.deflGroup{app.actSelDrop.Value}.ID';
                %gainTableの設定
                app.actGainTable.Data(:,1) = app.lsi.deflGroup{app.actSelDrop.Value}.ID';
                app.actGainTable.Data(:,2) = app.lsi.deflGroup{app.actSelDrop.Value}.gain;
            end

            
        end

        % Value changed function: actSelDrop
        function actSelDropValueChanged(app, event)
            uniqueID = unique(app.lsi.surfID);
            
            app.actGainTable.Data = [];
            app.actGainTable.Data(:,1) = app.lsi.deflGroup{app.actSelDrop.Value}.ID';
            app.actGainTable.Data(:,2) = app.lsi.deflGroup{app.actSelDrop.Value}.gain;

            app.actIDField.Items = {};
            app.actIDField.ItemsData = [];
            for i = 1:numel(uniqueID)
                app.actIDField.Items = [app.actIDField.Items,num2str(uniqueID(i))];
                app.actIDField.ItemsData = [app.actIDField.ItemsData,uniqueID(i)];
            end
            app.actIDField.Value = app.lsi.deflGroup{app.actSelDrop.Value}.ID';
            app.lsi = app.lsi.setDeflGroup(app.actSelDrop.Value,app.actSelDrop.Items(app.actSelDrop.Value),app.actIDField.Value,app.actGainTable.Data(:,2)'); % ID6番7番をgain[1,1](軸が同じなら同じ方向）に動かすことをelevatorとして登録(動翼ID1番） 
        end

        % Value changed function: actIDField
        function actIDFieldValueChanged(app, event)
            if not(isempty(app.actIDField.Value))
                uniqueID = unique(app.lsi.surfID);
                app.actGainTable.Data = [];
                app.actGainTable.Data(:,1) = uniqueID(app.actIDField.Value)';
                app.actGainTable.Data(:,2) = 0;  
                app.lsi = app.lsi.setDeflGroup(app.actSelDrop.Value,app.actSelDrop.Items(app.actSelDrop.Value),app.actIDField.Value,app.actGainTable.Data(:,2)'); % ID6番7番をgain[1,1](軸が同じなら同じ方向）に動かすことをelevatorとして登録(動翼ID1番） 
            else
                app.actGainTable.Data = [];
            end
        end

        % Display data changed function: actGainTable
        function actGainTableDisplayDataChanged(app, event)
            app.lsi = app.lsi.setDeflGroup(app.actSelDrop.Value,app.actSelDrop.Items(app.actSelDrop.Value),app.actIDField.Value,app.actGainTable.Data(:,2)'); % ID6番7番をgain[1,1](軸が同じなら同じ方向）に動かすことをelevatorとして登録(動翼ID1番） 
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if app.DropDown.Value<size(app.UITable3.Data,1)
                app.UITable3.Data(app.DropDown.Value+1:size(app.UITable3.Data,1),:) = [];
                app.UITable3_2.Data(app.DropDown.Value+1:size(app.UITable3_2.Data,1),:) = [];
            else
                for i = size(app.UITable3.Data,1):app.DropDown.Value
                    app.UITable3.Data(i,1:5) = [i,-5,0,5,3];
                    app.UITable3_2.Data(i,1:5) = [i,-5,0,5,3];
                end
            end
            
        end

        % Button pushed function: solveElasticButton_2
        function solveElasticButton_2Pushed(app, event)
            if isfield(app.var,"mFrame")
                if isempty(app.location)
                    [file,locbuff] = uiputfile("*.mp4","mp4ファイルで保存");
                else
                    [file,locbuff] = uiputfile("*.mp4","mp4ファイルで保存",app.location);
                end
                if not(file == 0)
                    app.location = locbuff;
                    guiFiles.videoMaker(app.var.mFrame,fullfile(app.location,file),app.var.mFrameRate);
                end
            end
        end

        % Button pushed function: execAnalysesButton_2
        function execAnalysesButton_2Pushed(app, event)
                if isempty(app.location)
                    [file,locbuff] = uiputfile("*.csv","csvファイルで保存");
                else
                    [file,locbuff] = uiputfile("*.csv","csvファイルで保存",app.location);
                end
                if not(file == 0)
                    app.location = locbuff;
                    %CSVで結果を出力
                    header1 = {'SREF', sprintf('%.2f', app.lsi.SREF), ...
                               'CREF', sprintf('%.2f', app.lsi.CREF), ...
                               'BREF', sprintf('%.2f', app.lsi.BREF), ...
                               'XREF', sprintf('%.2f', app.lsi.XYZREF(1)), ...
                               'YREF', sprintf('%.2f', app.lsi.XYZREF(2)), ...
                               'ZREF', sprintf('%.2f', app.lsi.XYZREF(3))};
                    
                    % ヘッダー情報2を定義（元のヘッダー）
                    header2 = {'Beta', 'Mach', 'AoA', 'Re/1e6', 'CLi', 'CLt', 'CDo', 'CDi', ...
                               'CDtot', 'CDt', 'CDtot_t', 'CS', 'L/D', 'E', 'CFx', 'CFy', ...
                               'CFz', 'CMx', 'CMy', 'CMz', 'CMl', 'CMm', 'CMn', 'FOpt'};            
                    
                    % 1行目に空白を埋め合わせてヘッダー1を配置
                    header1_full = [header1, repmat({''}, 1, numel(header2) - numel(header1))];
                    
                    % 2行目にヘッダー2を配置
                    header_combined = [header1_full; header2];
                    % ヘッダーとデータを結合
                    cellData = [header_combined; num2cell(app.lsi.AERODATA{app.flowSelectDrop.Value})];
                    % CSVファイルに書き出し
                    writecell(cellData, fullfile(app.location,file));
                end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {482, 482, 482};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {482, 482};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x', 320};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @Initialize, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout16 = uigridlayout(app.LeftPanel);
            app.Panel = uipanel(app.GridLayout16);
            app.Panel.Title = '基準値';
            app.GridLayout4 = uigridlayout(app.Panel);
            app.SREFEditFieldLabel = uilabel(app.GridLayout4);
            app.SREFEditFieldLabel.Text = 'SREF';
            app.SREFEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SREFEditField.Value = 1;
            app.BREFEditFieldLabel = uilabel(app.GridLayout4);
            app.BREFEditFieldLabel.Text = 'BREF';
            app.BREFEditField = uieditfield(app.GridLayout4, 'numeric');
            app.BREFEditField.Value = 1;
            app.CREFEditFieldLabel = uilabel(app.GridLayout4);
            app.CREFEditFieldLabel.Text = 'CREF';
            app.CREFEditField = uieditfield(app.GridLayout4, 'numeric');
            app.CREFEditField.Value = 1;
            app.XREFEditFieldLabel = uilabel(app.GridLayout4);
            app.XREFEditFieldLabel.Text = 'XREF';
            app.XREFEditField = uieditfield(app.GridLayout4, 'numeric');
            app.YREFEditFieldLabel = uilabel(app.GridLayout4);
            app.YREFEditFieldLabel.Text = 'YREF';
            app.YREFEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ZREFEditFieldLabel = uilabel(app.GridLayout4);
            app.ZREFEditFieldLabel.Text = 'ZREF';
            app.ZREFEditField = uieditfield(app.GridLayout4, 'numeric');
            app.Panel_3 = uipanel(app.GridLayout16);
            app.Panel_3.Title = '解析条件';
            app.GridLayout6 = uigridlayout(app.Panel_3);
            app.Label = uilabel(app.GridLayout6);
            app.Label.Text = '最小';
            app.Label_2 = uilabel(app.GridLayout6);
            app.Label_2.Text = '最大';
            app.Label_3 = uilabel(app.GridLayout6);
            app.Label_3.Text = '増分';
            app.degLabel_2 = uilabel(app.GridLayout6);
            app.degLabel_2.Text = 'α(deg)';
            app.degLabel = uilabel(app.GridLayout6);
            app.degLabel.Text = 'β(deg)';
            app.MachLabel = uilabel(app.GridLayout6);
            app.MachLabel.Text = 'Mach';
            app.Re1e4Label = uilabel(app.GridLayout6);
            app.Re1e4Label.Text = 'Re(*1e4)';
            app.alphaMinField = uieditfield(app.GridLayout6, 'numeric');
            app.alphaMaxField = uieditfield(app.GridLayout6, 'numeric');
            app.alphaMaxField.Value = 10;
            app.dalphaField = uieditfield(app.GridLayout6, 'numeric');
            app.dalphaField.Value = 1;
            app.betaMinField = uieditfield(app.GridLayout6, 'numeric');
            app.betaMaxField = uieditfield(app.GridLayout6, 'numeric');
            app.dbetaFiled = uieditfield(app.GridLayout6, 'numeric');
            app.dbetaFiled.Value = 1;
            app.MachField = uieditfield(app.GridLayout6, 'numeric');
            app.MachField.Value = 0.001;
            app.ReField = uieditfield(app.GridLayout6, 'numeric');
            app.ReField.Value = 50;
            app.Panel_8 = uipanel(app.GridLayout16);
            app.Panel_8.Title = '解析状況';
            app.GridLayout9 = uigridlayout(app.Panel_8);
            app.execAnalysesButton = uibutton(app.GridLayout9, 'push');
            app.execAnalysesButton.ButtonPushedFcn = createCallbackFcn(app, @execAnalysesButtonPushed, true);
            app.execAnalysesButton.Enable = 'off';
            app.execAnalysesButton.Text = '空力解析実行';
            app.flowSelectDrop = uidropdown(app.GridLayout9);
            app.flowSelectDrop.Items = {};
            app.flowSelectDrop.ValueChangedFcn = createCallbackFcn(app, @flowSelectDropValueChanged, true);
            app.flowSelectDrop.Enable = 'off';
            app.flowSelectDrop.Value = {};
            app.analysisProgressLabel = uilabel(app.GridLayout9);
            app.analysisProgressLabel.Text = '';
            app.alphaBetaDrop = uidropdown(app.GridLayout9);
            app.alphaBetaDrop.Items = {};
            app.alphaBetaDrop.ValueChangedFcn = createCallbackFcn(app, @alphaBetaDropValueChanged, true);
            app.alphaBetaDrop.Enable = 'off';
            app.alphaBetaDrop.Value = {};
            app.ADFlagDrop = uidropdown(app.GridLayout9);
            app.ADFlagDrop.Items = {'ペラ無', 'ペラ有'};
            app.ADFlagDrop.ItemsData = [0 1];
            app.ADFlagDrop.Value = 0;
            app.execAnalysesButton_2 = uibutton(app.GridLayout9, 'push');
            app.execAnalysesButton_2.ButtonPushedFcn = createCallbackFcn(app, @execAnalysesButton_2Pushed, true);
            app.execAnalysesButton_2.Enable = 'off';
            app.execAnalysesButton_2.Text = '結果出力';
            app.VSPGEOMPanel = uipanel(app.GridLayout16);
            app.VSPGEOMPanel.Title = 'VSPGEOM';
            app.GridLayout25 = uigridlayout(app.VSPGEOMPanel);
            app.VSPGEOMEditField = uieditfield(app.GridLayout25, 'text');
            app.makeEquationFIeld = uibutton(app.GridLayout25, 'push');
            app.makeEquationFIeld.ButtonPushedFcn = createCallbackFcn(app, @makeEquationFIeldPushed, true);
            app.makeEquationFIeld.Enable = 'off';
            app.makeEquationFIeld.Text = 'パネル法行列作成';
            app.Label_14 = uilabel(app.GridLayout25);
            app.Label_14.Text = '分割数';
            app.Spinner = uispinner(app.GridLayout25);
            app.Spinner.Limits = [1 20];
            app.Spinner.Value = 10;
            app.readMeshButton_2 = uibutton(app.GridLayout25, 'push');
            app.readMeshButton_2.ButtonPushedFcn = createCallbackFcn(app, @readMeshButton_2Pushed, true);
            app.readMeshButton_2.Text = '消去';
            app.readMeshButton = uibutton(app.GridLayout25, 'push');
            app.readMeshButton.ButtonPushedFcn = createCallbackFcn(app, @readMeshButtonPushed, true);
            app.readMeshButton.Text = '読込';
            app.Label_6 = uilabel(app.GridLayout16);
            app.Label_6.Text = 'マージ';
            app.mergeTolFiled = uieditfield(app.GridLayout16, 'numeric');
            app.mergeTolFiled.Limits = [0 Inf];
            app.Label_5 = uilabel(app.GridLayout16);
            app.Label_5.Text = '半全裁';
            app.halfMeshDrop = uidropdown(app.GridLayout16);
            app.halfMeshDrop.Items = {'自動設定', '半裁', '全裁'};
            app.halfMeshDrop.ItemsData = [-1 1 0];
            app.halfMeshDrop.Value = -1;
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '空力解析';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '舵面設定';
            app.GridLayout20 = uigridlayout(app.Tab_5);
            app.Panel_10 = uipanel(app.GridLayout20);
            app.Panel_10.Title = '回転軸設定';
            app.GridLayout21 = uigridlayout(app.Panel_10);
            app.UITable2 = uitable(app.GridLayout21);
            app.UITable2.ColumnName = {'ID'; '回転角'; 'X軸'; 'Y軸'; 'Z軸'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true true true true];
            app.UITable2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable2DisplayDataChanged, true);
            app.Panel_12 = uipanel(app.GridLayout20);
            app.Panel_12.Title = 'プロペラ設定';
            app.GridLayout23 = uigridlayout(app.Panel_12);
            app.propSelDrop = uidropdown(app.GridLayout23);
            app.propSelDrop.Items = {};
            app.propSelDrop.ValueChangedFcn = createCallbackFcn(app, @propSelChanged, true);
            app.propSelDrop.Value = {};
            app.CTEditField = uieditfield(app.GridLayout23, 'numeric');
            app.CTEditField.ValueChangedFcn = createCallbackFcn(app, @PropStateChanged, true);
            app.CPEditField = uieditfield(app.GridLayout23, 'numeric');
            app.CPEditField.ValueChangedFcn = createCallbackFcn(app, @PropStateChanged, true);
            app.RPMEditField = uieditfield(app.GridLayout23, 'numeric');
            app.RPMEditField.ValueChangedFcn = createCallbackFcn(app, @PropStateChanged, true);
            app.propIDField = uilistbox(app.GridLayout23);
            app.propIDField.Items = {};
            app.propIDField.Multiselect = 'on';
            app.propIDField.ValueChangedFcn = createCallbackFcn(app, @propChanged, true);
            app.propIDField.Value = {};
            app.CTLabel = uilabel(app.GridLayout23);
            app.CTLabel.Text = 'CT';
            app.CPLabel = uilabel(app.GridLayout23);
            app.CPLabel.Text = 'CP';
            app.rpmLabel = uilabel(app.GridLayout23);
            app.rpmLabel.Text = 'rpm';
            app.EditField_3Label = uilabel(app.GridLayout23);
            app.EditField_3Label.Text = 'ペラの数';
            app.propNoField = uieditfield(app.GridLayout23, 'numeric');
            app.propNoField.Limits = [0 Inf];
            app.propNoField.RoundFractionalValues = 'on';
            app.propNoField.ValueDisplayFormat = '%.0f';
            app.propNoField.ValueChangedFcn = createCallbackFcn(app, @propNoFieldValueChanged, true);
            app.propSymField = uidropdown(app.GridLayout23);
            app.propSymField.Items = {'対称', '非対象'};
            app.propSymField.ItemsData = [1 0];
            app.propSymField.ValueChangedFcn = createCallbackFcn(app, @propChanged, true);
            app.propSymField.Value = 1;
            app.Label_11 = uilabel(app.GridLayout23);
            app.Label_11.Text = 'ペラ直径';
            app.propDiaField = uieditfield(app.GridLayout23, 'numeric');
            app.propDiaField.Limits = [0 Inf];
            app.propDiaField.ValueChangedFcn = createCallbackFcn(app, @propChanged, true);
            app.Panel_13 = uipanel(app.GridLayout20);
            app.Panel_13.Title = '舵面ゲイン設定';
            app.GridLayout24 = uigridlayout(app.Panel_13);
            app.actIDField = uilistbox(app.GridLayout24);
            app.actIDField.Items = {};
            app.actIDField.Multiselect = 'on';
            app.actIDField.ValueChangedFcn = createCallbackFcn(app, @actIDFieldValueChanged, true);
            app.actIDField.Value = {};
            app.EditField_4Label = uilabel(app.GridLayout24);
            app.EditField_4Label.Text = '舵面の数';
            app.actNoField = uieditfield(app.GridLayout24, 'numeric');
            app.actNoField.Limits = [0 Inf];
            app.actNoField.RoundFractionalValues = 'on';
            app.actNoField.ValueDisplayFormat = '%.0f';
            app.actNoField.ValueChangedFcn = createCallbackFcn(app, @actNoFieldValueChanged, true);
            app.actSelDrop = uidropdown(app.GridLayout24);
            app.actSelDrop.Items = {''};
            app.actSelDrop.ValueChangedFcn = createCallbackFcn(app, @actSelDropValueChanged, true);
            app.actSelDrop.Value = '';
            app.actGainTable = uitable(app.GridLayout24);
            app.actGainTable.ColumnName = {'ID'; 'ゲイン'};
            app.actGainTable.RowName = {};
            app.actGainTable.ColumnEditable = [false true];
            app.actGainTable.DisplayDataChangedFcn = createCallbackFcn(app, @actGainTableDisplayDataChanged, true);
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '動安定解析';
            app.GridLayout17 = uigridlayout(app.Tab_4);
            app.UIAxes6_2 = uiaxes(app.GridLayout17);
            title(app.UIAxes6_2, 'Title')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            zlabel(app.UIAxes6_2, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout17);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Panel_9 = uipanel(app.GridLayout17);
            app.Panel_9.Title = '空力微係数推算';
            app.GridLayout18 = uigridlayout(app.Panel_9);
            app.dynCoefButton = uibutton(app.GridLayout18, 'push');
            app.dynCoefButton.ButtonPushedFcn = createCallbackFcn(app, @dynCoefButtonPushed, true);
            app.dynCoefButton.Enable = 'off';
            app.dynCoefButton.Text = '無次元解析';
            app.Button_5 = uibutton(app.GridLayout18, 'push');
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '未実装';
            app.massPropField = uieditfield(app.GridLayout18, 'text');
            app.readMassPropButton = uibutton(app.GridLayout18, 'push');
            app.readMassPropButton.ButtonPushedFcn = createCallbackFcn(app, @readMassPropButtonPushed, true);
            app.readMassPropButton.Enable = 'off';
            app.readMassPropButton.Text = '読込';
            app.dimAnalysisButton = uibutton(app.GridLayout18, 'push');
            app.dimAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @dimAnalysisButtonPushed, true);
            app.dimAnalysisButton.Enable = 'off';
            app.dimAnalysisButton.Text = '有次元解析';
            app.Label_12 = uilabel(app.GridLayout18);
            app.Label_12.Text = '基準速度';
            app.dynVinfFIeld = uieditfield(app.GridLayout18, 'numeric');
            app.dynVinfFIeld.Value = 15;
            app.readMassPropButton_2 = uibutton(app.GridLayout18, 'push');
            app.readMassPropButton_2.ButtonPushedFcn = createCallbackFcn(app, @readMassPropButton_2Pushed, true);
            app.readMassPropButton_2.Enable = 'off';
            app.readMassPropButton_2.Text = '消去';
            app.msLabel = uilabel(app.GridLayout18);
            app.msLabel.Text = 'm/s';
            app.TextArea = uitextarea(app.GridLayout17);
            app.TextArea.Editable = 'off';
            app.Button_8 = uibutton(app.GridLayout17, 'push');
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '構造解析設定';
            app.GridLayout15 = uigridlayout(app.Tab_3);
            app.FEMmeshPanel = uipanel(app.GridLayout15);
            app.FEMmeshPanel.Title = 'FEMmesh';
            app.GridLayout8 = uigridlayout(app.FEMmeshPanel);
            app.readFemMeshButton = uibutton(app.GridLayout8, 'push');
            app.readFemMeshButton.ButtonPushedFcn = createCallbackFcn(app, @readFemMeshPushed, true);
            app.readFemMeshButton.Enable = 'off';
            app.readFemMeshButton.Text = '読込';
            app.FEAmeshEditField = uieditfield(app.GridLayout8, 'text');
            app.makeFemEquationButton = uibutton(app.GridLayout8, 'push');
            app.makeFemEquationButton.ButtonPushedFcn = createCallbackFcn(app, @makeFemEquationPushed, true);
            app.makeFemEquationButton.Enable = 'off';
            app.makeFemEquationButton.Text = 'FEM行列作成';
            app.modalDimField = uieditfield(app.GridLayout8, 'numeric');
            app.modalDimField.Limits = [1 Inf];
            app.modalDimField.Value = 15;
            app.Label_13 = uilabel(app.GridLayout8);
            app.Label_13.Text = 'モード次数';
            app.modalNoDrop = uidropdown(app.GridLayout8);
            app.modalNoDrop.Items = {};
            app.modalNoDrop.ValueChangedFcn = createCallbackFcn(app, @modalShowButtonPushed, true);
            app.modalNoDrop.Enable = 'off';
            app.modalNoDrop.Value = {};
            app.readFemMeshButton_2 = uibutton(app.GridLayout8, 'push');
            app.readFemMeshButton_2.ButtonPushedFcn = createCallbackFcn(app, @readFemMeshButton_2Pushed, true);
            app.readFemMeshButton_2.Enable = 'off';
            app.readFemMeshButton_2.Text = '消去';
            app.solveFemButton = uibutton(app.GridLayout8, 'push');
            app.solveFemButton.ButtonPushedFcn = createCallbackFcn(app, @solveFemPushed, true);
            app.solveFemButton.Enable = 'off';
            app.solveFemButton.Text = '実行';
            app.selfLoadDrop = uidropdown(app.GridLayout8);
            app.selfLoadDrop.Items = {'有自重', '無自重'};
            app.selfLoadDrop.ItemsData = [1 0];
            app.selfLoadDrop.Enable = 'off';
            app.selfLoadDrop.Value = 1;
            app.Label_17 = uilabel(app.GridLayout8);
            app.Label_17.Text = '風速';
            app.femVinfFIeld = uieditfield(app.GridLayout8, 'numeric');
            app.femVinfFIeld.Enable = 'off';
            app.femVinfFIeld.Value = 15;
            app.IDPanel = uipanel(app.GridLayout15);
            app.IDPanel.Title = '動かす空力メッシュのID';
            app.GridLayout12 = uigridlayout(app.IDPanel);
            app.fem2aeroList = uilistbox(app.GridLayout12);
            app.fem2aeroList.Items = {};
            app.fem2aeroList.Multiselect = 'on';
            app.fem2aeroList.ValueChangedFcn = createCallbackFcn(app, @fem2aeroListValueChanged, true);
            app.fem2aeroList.Value = {};
            app.TabGroup2 = uitabgroup(app.GridLayout15);
            app.Tab_6 = uitab(app.TabGroup2);
            app.Tab_6.Title = 'メッシュ';
            app.GridLayout27 = uigridlayout(app.Tab_6);
            app.UIAxes5 = uiaxes(app.GridLayout27);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Tab_7 = uitab(app.TabGroup2);
            app.Tab_7.Title = '剛性・主軸';
            app.GridLayout28 = uigridlayout(app.Tab_7);
            app.UIAxes7 = uiaxes(app.GridLayout28);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.Panel_6 = uipanel(app.GridLayout15);
            app.Panel_6.Title = '物性値設定';
            app.GridLayout10 = uigridlayout(app.Panel_6);
            app.UITable = uitable(app.GridLayout10);
            app.UITable.ColumnName = {'ID'; '肉厚(mm)'; 'E(GPa)'; '密度(kg/m3)'; '減衰'; '重量(kg)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true];
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @setFemMaterialButtonPushed, true);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'フラッター解析';
            app.GridLayout14 = uigridlayout(app.Tab_2);
            app.UIAxes5_2 = uiaxes(app.GridLayout14);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.Panel_14 = uipanel(app.GridLayout14);
            app.Panel_14.Title = 'フラッター解析';
            app.GridLayout26 = uigridlayout(app.Panel_14);
            app.Button_7 = uibutton(app.GridLayout26, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '実行';
            app.Label_19 = uilabel(app.GridLayout26);
            app.Label_19.Text = '風速';
            app.femVinfFIeld_2 = uieditfield(app.GridLayout26, 'numeric');
            app.femVinfFIeld_2.Value = 15;
            app.TextArea_2 = uitextarea(app.GridLayout14);
            app.Panel_16 = uipanel(app.GridLayout14);
            app.Panel_16.Title = '空力応答局面';
            app.GridLayout26_2 = uigridlayout(app.Panel_16);
            app.Label_18 = uilabel(app.GridLayout26_2);
            app.Label_18.Text = '最大モード次数';
            app.DropDown = uidropdown(app.GridLayout26_2);
            app.DropDown.Items = {'1'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1';
            app.Button_6 = uibutton(app.GridLayout26_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '準備';
            app.TabGroup3 = uitabgroup(app.GridLayout14);
            app.zTab = uitab(app.TabGroup3);
            app.zTab.Title = 'z';
            app.GridLayout30 = uigridlayout(app.zTab);
            app.UITable3 = uitable(app.GridLayout30);
            app.UITable3.ColumnName = {'Modal No'; 'Min'; 'Nom'; 'Max'; 'Num'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = [false true true true true];
            app.zdotTab = uitab(app.TabGroup3);
            app.zdotTab.Title = 'zdot';
            app.UITable3_2 = uitable(app.zdotTab);
            app.UITable3_2.ColumnName = {'Modal No'; 'Min'; 'Nom'; 'Max'; 'Num'};
            app.UITable3_2.RowName = {};
            app.UITable3_2.ColumnEditable = [false true true true true];
            app.Tab_8 = uitab(app.TabGroup);
            app.Tab_8.Title = '時系列解析';
            app.GridLayout29 = uigridlayout(app.Tab_8);
            app.UIAxes4 = uiaxes(app.GridLayout29);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4_2 = uiaxes(app.GridLayout29);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            zlabel(app.UIAxes4_2, 'Z')
            app.Panel_15 = uipanel(app.GridLayout29);
            app.Panel_15.Title = '空力弾性解析';
            app.GridLayout11_4 = uigridlayout(app.Panel_15);
            app.Label_15 = uilabel(app.GridLayout11_4);
            app.Label_15.Text = '時間';
            app.elasticTimeField = uieditfield(app.GridLayout11_4, 'numeric');
            app.elasticTimeField.Enable = 'off';
            app.elasticTimeField.Value = 5;
            app.Label_16 = uilabel(app.GridLayout11_4);
            app.Label_16.Text = 'Δt';
            app.elasticdtField = uieditfield(app.GridLayout11_4, 'numeric');
            app.elasticdtField.Enable = 'off';
            app.elasticdtField.Value = 0.01;
            app.solveElasticButton = uibutton(app.GridLayout11_4, 'push');
            app.solveElasticButton.ButtonPushedFcn = createCallbackFcn(app, @solveElasticButtonPushed, true);
            app.solveElasticButton.Enable = 'off';
            app.solveElasticButton.Text = '実行';
            app.abortDrop = uidropdown(app.GridLayout11_4);
            app.abortDrop.Items = {'続行', '停止'};
            app.abortDrop.ItemsData = [0 1];
            app.abortDrop.Enable = 'off';
            app.abortDrop.Value = 0;
            app.solveElasticButton_2 = uibutton(app.GridLayout11_4, 'push');
            app.solveElasticButton_2.ButtonPushedFcn = createCallbackFcn(app, @solveElasticButton_2Pushed, true);
            app.solveElasticButton_2.Enable = 'off';
            app.solveElasticButton_2.Text = '動画保存';
            app.abortDrop_2 = uidropdown(app.GridLayout11_4);
            app.abortDrop_2.Items = {'記録なし', '動画記録'};
            app.abortDrop_2.ItemsData = [0 1];
            app.abortDrop_2.Enable = 'off';
            app.abortDrop_2.Value = 0;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout13 = uigridlayout(app.RightPanel);
            app.UIAxes_2 = uiaxes(app.GridLayout13);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_2 = uipanel(app.GridLayout13);
            app.Panel_2.Title = 'Panel';
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.xlabelList = uilistbox(app.GridLayout5);
            app.xlabelList.Items = {'Alfa', 'Beta', 'CLi', 'CLt', 'CDi', 'CDtot', 'CDt', 'CDtot_t'};
            app.xlabelList.ItemsData = [3 1 5 6 8 9 10 11];
            app.xlabelList.ValueChangedFcn = createCallbackFcn(app, @xlabelListValueChanged, true);
            app.xlabelList.Enable = 'off';
            app.xlabelList.Value = 3;
            app.ylabelList = uilistbox(app.GridLayout5);
            app.ylabelList.Items = {'CLi', 'CLt', 'CDi', 'CDtot', 'CDt', 'CDtot_t', 'CS', 'L/D', 'e', 'CMx', 'CMy', 'CMz'};
            app.ylabelList.ItemsData = [5 6 8 9 10 11 12 13 14 18 19 20];
            app.ylabelList.Multiselect = 'on';
            app.ylabelList.ValueChangedFcn = createCallbackFcn(app, @ylabelListValueChanged, true);
            app.ylabelList.Enable = 'off';
            app.ylabelList.Value = 5;
            app.XLabel = uilabel(app.GridLayout5);
            app.XLabel.Text = 'X軸';
            app.YLabel = uilabel(app.GridLayout5);
            app.YLabel.Text = 'Y軸';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UNLSI_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end